classdef PreloadAnalysisApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        FileMenu              matlab.ui.container.Menu
        SaveMenu              matlab.ui.container.Menu
        AnalysisPanel         matlab.ui.container.Panel
        ResultsTextArea       matlab.ui.control.TextArea
        FittingPanel          matlab.ui.container.Panel
        RemoveButton          matlab.ui.control.Button
        ClearAllButton        matlab.ui.control.Button
        DatasetsListBox       matlab.ui.control.ListBox
        DatasetsListBoxLabel  matlab.ui.control.Label
        ImportDatasetPanel    matlab.ui.container.Panel
        OpenLabel             matlab.ui.control.Label
        Button                matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        hLeg % plot legend handle
        hPlot % plot handle
        datasets = dictionary([],Dataset([])) % dictionary of imported datasets
        totalSets = 0 % counter for imported dataset
        dataSetListStrings = {} % strings to display for each dataset in the list box
        dataSetListKeys = {} % key for each dataset in the listbox
    end
    
    methods (Access = private)

        % solving algorithm
        function mdl = solver(app, tdata, force)
            % x0 = [100, -1, 100, -1]; % initial guess values
            % x = lsqcurvefit(fun, x0, tdata, force);
            % app.datasets(app.DatasetsListBox.Value).coefficients = x; % add the solved coefficients to the dataset object
            mdl = fit(tdata', force', 'exp2');
        end
        
        % cfit objects cannot be plotted in UIAxes directly. Workaround
        function handler = plotcfit(app, time, force)
            value = cell2mat(app.DatasetsListBox.Value);

            % create temp fig
            tempFig = figure('Visible','off');
            ax = axes(tempFig);
            plot(app.datasets(value(1)).mdl, time, force, 'ko');

            datalabel = erase(app.datasets(value(1)).setName, '.csv');
            title(app.UIAxes, strcat(datalabel, ' Data and Fitted Curve'));
            xlabel(app.UIAxes, 'Cycles');
            ylabel(app.UIAxes, 'Force Percentage');

            % Copy content of temp fig to app designer
            handler = copyobj(ax.Children, app.UIAxes);
            % Delete temp figure
            delete(tempFig);
        end

        function handler = plotcfit2(app, time1, force1, time2, force2)
            value = cell2mat(app.DatasetsListBox.Value);

            % create temp fig
            tempFig = figure('Visible','off');
            ax = axes(tempFig);
            hold(ax, 'on');
            plot(time1, force1, 'ko', time2, force2, 'ko');
            plot(app.datasets(value(1)).mdl, '-r');
            plot(app.datasets(value(2)).mdl, '-b');
            
            title(app.UIAxes, 'Data and Fitted Curve');
            xlabel(app.UIAxes, 'Cycles');
            ylabel(app.UIAxes, 'Force Percentage');

            % Copy axes of temp fig to app designer
            handler = copyobj(ax.Children, app.UIAxes);
            % Delete temp figure
            delete(tempFig);
        end
        
        function [tdata, force] = getPlotData(app, index)
            table = app.datasets(index).data;
            tdata = table2array(table(1,:));
            force = table2array(table(2,:));
        end
        
        function clearApp(app)
            % reset plot
            app.UIAxes.reset;
            set(app.hLeg, 'visible', 'off');
            set(app.hPlot, 'visible', 'off');
            app.OpenLabel.Text = "Open...";
            % reset gui values
            app.DatasetsListBox.Value = {};
            app.DatasetsListBox.Items = {};
            app.DatasetsListBox.ItemsData = {};
            app.ResultsTextArea.Value = {''};
            % reset button states
            app.ClearAllButton.Enable = "off";
            app.RemoveButton.Enable = "off";
            % reset properties
            app.datasets = dictionary([],Dataset([]));
            app.totalSets = 0;
            app.dataSetListStrings = {};
            app.dataSetListKeys = {};
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % import data file
            [file, path] = uigetfile({'*.csv';'*.xls'},...
                          'Select Data File');
            if isequal(file, 0)
                % user selected cancel
            else
                % successfully imported file
                app.totalSets = app.totalSets + 1; % increment file count

                % add dataset to dictionary
                app.datasets(app.totalSets) = Dataset(file, path);

                % append dataset name to listbox
                app.dataSetListStrings{end + 1} = app.datasets(app.totalSets).setName;
                app.DatasetsListBox.Items = app.dataSetListStrings;

                % append dataset key to listbox
                app.dataSetListKeys{end + 1} = app.totalSets;
                app.DatasetsListBox.ItemsData = app.dataSetListKeys;

                % display imported file name
                app.OpenLabel.Text = app.datasets(app.totalSets).setName;

                % solve dataset
                [tdata, force] = getPlotData(app, app.totalSets);
                app.datasets(app.totalSets).mdl = solver(app, tdata, force);
            end
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            if (app.ClearAllButton.Enable == true)
                clearApp(app);
            end
        end

        % Value changed function: DatasetsListBox
        function DatasetsListBoxValueChanged(app, event)
            % when user selects a listed dataset
            value = cell2mat(app.DatasetsListBox.Value);

            % clear current plot
            app.UIAxes.reset;
            %set(app.hLeg, 'visible', 'off');
            set(app.hPlot, 'visible', 'off');
            % if one dataset is selected
            if (size(value, 2) == 1)
                [tdata, force] = getPlotData(app, value(1));

                % plot the dataset
                app.hPlot = plotcfit(app, tdata, force);

                % legend for the plot
                datalabel = erase(app.datasets(value(1)).setName, '.csv');
                app.hLeg = legend(app.UIAxes, datalabel,'Fitted exponential');
                % display results
                app.ResultsTextArea.Value = strcat(datalabel, '= ') + formattedDisplayText(app.datasets(value(1)).mdl);

                % set button states
                app.ClearAllButton.Enable = "on";
                app.RemoveButton.Enable = "on";

            % if two datasets are selected
            elseif (size(value, 2) == 2)
                [tdata1, force1] = getPlotData(app, value(1));
                [tdata2, force2] = getPlotData(app, value(2));

                app.hPlot = plotcfit2(app, tdata1, force1, tdata2, force2);

                % legend for the plot
                datalabel1 = erase(app.datasets(value(1)).setName, '.csv');
                datalabel2 = erase(app.datasets(value(2)).setName, '.csv');
                app.hLeg = legend(app.UIAxes, datalabel1,'Fitted exponential', datalabel2, 'Fitted exponential');
                % display results
                app.ResultsTextArea.Value =...
                (strcat(datalabel1, '= ') + formattedDisplayText(app.datasets(value(1)).mdl))... 
                + newline + newline...
                + (strcat(datalabel2, '= ') + formattedDisplayText(app.datasets(value(2)).mdl));

                % set button states
                app.ClearAllButton.Enable = "on";
                app.RemoveButton.Enable = "off";
            else
                app.RemoveButton.Enable = "off";
            end
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            if (app.RemoveButton.Enable == true)
                % if only one dataset, treat it as clear all
                if (app.totalSets == 1)
                    clearApp(app);
                else
                    value = cell2mat(app.DatasetsListBox.Value);
                    if ~(isempty(value(1)))
                        % update listbox & dataset names
                        app.dataSetListStrings(value(1)) = [];
                        app.DatasetsListBox.Items = app.dataSetListStrings;
    
                        % update listbox & dataset keys
                        app.dataSetListKeys(value(1)) = [];
                        app.DatasetsListBox.ItemsData = app.dataSetListKeys;
    
                        % clear plot if it's the removed data
                        datalabel = erase(app.datasets(value(1)).setName, '.csv');
                        title = erase(app.UIAxes.Title.String, ' Data and Fitted Curve');
                        if (strcmp(title, datalabel))
                            app.UIAxes.reset;
                            set(app.hLeg, 'visible', 'off');
                            set(app.hPlot, 'visible', 'off');
                        end
    
                        % clear coefficient text
                        app.ResultsTextArea.Value = {''};
    
                        % remove from dictionary
                        app.datasets(value) = []; % remove the selected entry
                        app.totalSets = app.totalSets - 1;
    
                        % update value
                        app.DatasetsListBox.Value = {};
                        app.RemoveButton.Enable = "off";
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Text = 'Save';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.ImportDatasetPanel = uipanel(app.UIFigure);
            app.ImportDatasetPanel.Title = 'Import Dataset';
            app.Button = uibutton(app.ImportDatasetPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'assets', 'open-folder.png');
            app.Button.Tooltip = {'select file...'};
            app.Button.Text = '';
            app.OpenLabel = uilabel(app.ImportDatasetPanel);
            app.OpenLabel.Text = 'Open...';
            app.FittingPanel = uipanel(app.UIFigure);
            app.FittingPanel.Title = 'Fitting';
            app.DatasetsListBoxLabel = uilabel(app.FittingPanel);
            app.DatasetsListBoxLabel.Text = 'Datasets';
            app.DatasetsListBox = uilistbox(app.FittingPanel);
            app.DatasetsListBox.Items = {};
            app.DatasetsListBox.Multiselect = 'on';
            app.DatasetsListBox.ValueChangedFcn = createCallbackFcn(app, @DatasetsListBoxValueChanged, true);
            app.DatasetsListBox.Value = {};
            app.ClearAllButton = uibutton(app.FittingPanel, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Enable = 'off';
            app.ClearAllButton.Text = 'Clear All';
            app.RemoveButton = uibutton(app.FittingPanel, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Enable = 'off';
            app.RemoveButton.Text = 'Remove';
            app.AnalysisPanel = uipanel(app.UIFigure);
            app.AnalysisPanel.Title = 'Analysis';
            app.ResultsTextArea = uitextarea(app.AnalysisPanel);
            app.ResultsTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PreloadAnalysisApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end