classdef PreloadAnalysisApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        SaveMenu                   matlab.ui.container.Menu
        AnalysisPanel              matlab.ui.container.Panel
        Coefficient4TextArea       matlab.ui.control.TextArea
        Coefficient4TextAreaLabel  matlab.ui.control.Label
        Coefficient3TextArea       matlab.ui.control.TextArea
        Coefficient3TextAreaLabel  matlab.ui.control.Label
        Coefficient2TextArea       matlab.ui.control.TextArea
        Coefficient2TextAreaLabel  matlab.ui.control.Label
        Coefficient1TextArea       matlab.ui.control.TextArea
        Coefficient1TextAreaLabel  matlab.ui.control.Label
        FittingPanel               matlab.ui.container.Panel
        RemoveButton               matlab.ui.control.Button
        ClearAllButton             matlab.ui.control.Button
        DatasetsListBox            matlab.ui.control.ListBox
        DatasetsListBoxLabel       matlab.ui.control.Label
        ImportDatasetPanel         matlab.ui.container.Panel
        OpenLabel                  matlab.ui.control.Label
        Button                     matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        hLeg % plot legend handle
        hPlot % plot handle
        datasets = dictionary([],Dataset([])) % array of imported datasets
        totalSets = 0 % counter for imported dataset
        dataSetListStrings = {} % strings to display for each dataset in the list box
        dataSetListKeys = {} % key for each dataset in the listbox
    end
    
    methods (Access = private)

        % solving algorithm
        function solver(app, fun, tdata, force)
            x0 = [100, -1, 100, -1]; % initial guess values
            x = lsqcurvefit(fun, x0, tdata, force);
            app.datasets(app.DatasetsListBox.Value).coefficients = x; % add the solved coefficients to the dataset object
        end
        
        % updates the coefficients in the app's coefficient text area
        function updateCoefficientText(app)
            value = app.DatasetsListBox.Value;
            if (size(app.datasets(value).coefficients, 2) >= 4)
                app.Coefficient1TextArea.Value = {numToString(app, app.datasets(value).coefficients(1))};
                app.Coefficient2TextArea.Value = {numToString(app, app.datasets(value).coefficients(2))};
                app.Coefficient3TextArea.Value = {numToString(app, app.datasets(value).coefficients(3))};
                app.Coefficient4TextArea.Value = {numToString(app, app.datasets(value).coefficients(4))};
            end
        end
        
        % converts a number to string keeping precision
        function numString = numToString(app, num)
            numString = sprintf('%.20f', num);
            idx = regexp(numString,'0+','start');
            numString(idx(end):end) = [];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % import data file
            [file, path] = uigetfile({'*.csv';'*.xls'},...
                          'Select Data File');
            if isequal(file, 0)
                % user selected cancel
            else
                % successfully imported file
                app.totalSets = app.totalSets + 1; % increment file count
                app.datasets(app.totalSets) = Dataset(file, path); % add dataset to dictionary

                % append dataset name to listbox
                app.dataSetListStrings{end + 1} = app.datasets(app.totalSets).setName;
                app.DatasetsListBox.Items = app.dataSetListStrings;

                % append dataset key to listbox
                app.dataSetListKeys{end + 1} = app.totalSets;
                app.DatasetsListBox.ItemsData = app.dataSetListKeys;

                % display imported file name
                app.OpenLabel.Text = app.datasets(app.totalSets).setName;
            end
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            if (app.ClearAllButton.Enable == true)

                % reset plot
                app.UIAxes.reset;
                set(app.hLeg, 'visible', 'off');
                set(app.hPlot, 'visible', 'off');
                app.OpenLabel.Text = "Open...";

                % reset gui values
                app.DatasetsListBox.Value = {};
                app.DatasetsListBox.Items = {};
                app.DatasetsListBox.ItemsData = {};
                app.Coefficient1TextArea.Value = {'0'};
                app.Coefficient2TextArea.Value = {'0'};
                app.Coefficient3TextArea.Value = {'0'};
                app.Coefficient4TextArea.Value = {'0'};

                % reset button states
                app.ClearAllButton.Enable = "off";
                app.RemoveButton.Enable = "off";

                % reset properties
                app.datasets = dictionary([],Dataset([]));
                app.totalSets = 0;
                app.dataSetListStrings = {};
                app.dataSetListKeys = {};
            end
        end

        % Value changed function: DatasetsListBox
        function DatasetsListBoxValueChanged(app, event)
            % when user selects a listed dataset
            if ~(isempty(app.DatasetsListBox.Value))
                % initialize values from dataset
                value = app.DatasetsListBox.Value;
                table = app.datasets(value).data;
                tdata = table2array(table(1,:));
                force = table2array(table(2,:));
                % label for the plot
                datalabel = erase(app.datasets(value).setName, '.csv');

                % the function we are solving for
                fun = @(x,time) x(1)*exp(x(2)*time)+x(3)*exp(x(4)*time);

                if (isempty(app.datasets(value).coefficients))
                % only solve if the dataset does not have solved
                % coefficients already
                solver(app, fun, tdata, force);
                end

                times = linspace(tdata(1),tdata(end));
                app.hPlot = plot(app.UIAxes, tdata, force, 'ko', times, fun(app.datasets(value).coefficients, times), 'b-');
                app.hLeg = legend(app.UIAxes, datalabel, 'fitted exponential');
                title(app.UIAxes, strcat(datalabel, ' Data and Fitted Curve'));
                xlabel(app.UIAxes, 'Time Sample');
                ylabel(app.UIAxes, 'Force Percentage');

                updateCoefficientText(app); % show the solved coefficients

                % set button states
                app.ClearAllButton.Enable = "on";
                app.RemoveButton.Enable = "on";
            else
                app.RemoveButton.Enable = "off";
            end
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            if (app.RemoveButton.Enable == true)
                value = app.DatasetsListBox.Value;
                if ~(isempty(value))
                    % update listbox & dataset names
                    app.dataSetListStrings(value) = [];
                    app.DatasetsListBox.Items = app.dataSetListStrings;

                    % update listbox & dataset keys
                    app.dataSetListKeys(value) = [];
                    app.DatasetsListBox.ItemsData = app.dataSetListKeys;

                    % clear plot if it's the removed data
                    datalabel = erase(app.datasets(value).setName, '.csv');
                    title = erase(app.UIAxes.Title.String, ' Data and Fitted Curve');
                    if (strcmp(title, datalabel))
                        app.UIAxes.reset;
                        set(app.hLeg, 'visible', 'off');
                        set(app.hPlot, 'visible', 'off');
                    end

                    % clear coefficient text
                    app.Coefficient1TextArea.Value = {'0'};
                    app.Coefficient2TextArea.Value = {'0'};
                    app.Coefficient3TextArea.Value = {'0'};
                    app.Coefficient4TextArea.Value = {'0'};

                    % remove from dictionary
                    app.datasets(value) = []; % remove the selected entry
                    app.totalSets = app.totalSets - 1;

                    % update value
                    app.DatasetsListBox.Value = {};
                    app.RemoveButton.Enable = "off";
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Text = 'Save';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.ImportDatasetPanel = uipanel(app.UIFigure);
            app.ImportDatasetPanel.Title = 'Import Dataset';
            app.Button = uibutton(app.ImportDatasetPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'assets', 'open-folder.png');
            app.Button.Tooltip = {'select file...'};
            app.Button.Text = '';
            app.OpenLabel = uilabel(app.ImportDatasetPanel);
            app.OpenLabel.Text = 'Open...';
            app.FittingPanel = uipanel(app.UIFigure);
            app.FittingPanel.Title = 'Fitting';
            app.DatasetsListBoxLabel = uilabel(app.FittingPanel);
            app.DatasetsListBoxLabel.Text = 'Datasets';
            app.DatasetsListBox = uilistbox(app.FittingPanel);
            app.DatasetsListBox.Items = {};
            app.DatasetsListBox.ValueChangedFcn = createCallbackFcn(app, @DatasetsListBoxValueChanged, true);
            app.DatasetsListBox.Value = {};
            app.ClearAllButton = uibutton(app.FittingPanel, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Enable = 'off';
            app.ClearAllButton.Text = 'Clear All';
            app.RemoveButton = uibutton(app.FittingPanel, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Enable = 'off';
            app.RemoveButton.Text = 'Remove';
            app.AnalysisPanel = uipanel(app.UIFigure);
            app.AnalysisPanel.Title = 'Analysis';
            app.Coefficient1TextAreaLabel = uilabel(app.AnalysisPanel);
            app.Coefficient1TextAreaLabel.Text = 'Coefficient 1:';
            app.Coefficient1TextArea = uitextarea(app.AnalysisPanel);
            app.Coefficient1TextArea.Editable = 'off';
            app.Coefficient1TextArea.Placeholder = '0';
            app.Coefficient2TextAreaLabel = uilabel(app.AnalysisPanel);
            app.Coefficient2TextAreaLabel.Text = 'Coefficient 2:';
            app.Coefficient2TextArea = uitextarea(app.AnalysisPanel);
            app.Coefficient2TextArea.Editable = 'off';
            app.Coefficient2TextArea.Placeholder = '0';
            app.Coefficient3TextAreaLabel = uilabel(app.AnalysisPanel);
            app.Coefficient3TextAreaLabel.Text = 'Coefficient 3:';
            app.Coefficient3TextArea = uitextarea(app.AnalysisPanel);
            app.Coefficient3TextArea.Editable = 'off';
            app.Coefficient3TextArea.Placeholder = '0';
            app.Coefficient4TextAreaLabel = uilabel(app.AnalysisPanel);
            app.Coefficient4TextAreaLabel.Text = 'Coefficient 4:';
            app.Coefficient4TextArea = uitextarea(app.AnalysisPanel);
            app.Coefficient4TextArea.Editable = 'off';
            app.Coefficient4TextArea.Placeholder = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PreloadAnalysisApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end